classdef Image_Based_Retrospective_Gating < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExamineDiaphragmandCoilsPanel   matlab.ui.container.Panel
        ReconstructScoutImageButton     matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        SlidingWindowReconPanel         matlab.ui.container.Panel
        DimensionSliderLabel            matlab.ui.control.Label
        DimensionSlider                 matlab.ui.control.Slider
        SlidingWindowReconButton        matlab.ui.control.Button
        PercentCompleteGaugeLabel       matlab.ui.control.Label
        PercentCompleteGauge            matlab.ui.control.SemicircularGauge
        Lamp_2                          matlab.ui.control.Lamp
        SliceEditField_2Label           matlab.ui.control.Label
        SliceEditField                  matlab.ui.control.NumericEditField
        CoilsEditFieldLabel             matlab.ui.control.Label
        CoilsEditField                  matlab.ui.control.NumericEditField
        CoilsEditField_2                matlab.ui.control.NumericEditField
        DataAvailablePanel              matlab.ui.container.Panel
        FIDsAvailableCheckBox           matlab.ui.control.CheckBox
        TrajectoriesAvailableCheckBox   matlab.ui.control.CheckBox
        LoadRadialButton                matlab.ui.control.Button
        LoadSpiralButton                matlab.ui.control.Button
        ConsistentValuesPanel           matlab.ui.container.Panel
        AcquiredDesiredImageSizeEditFieldLabel  matlab.ui.control.Label
        AcquiredImageSizeEditField      matlab.ui.control.NumericEditField
        FieldofViewEditFieldLabel       matlab.ui.control.Label
        FieldofViewEditField            matlab.ui.control.NumericEditField
        ScoutImageSizeEditFieldLabel    matlab.ui.control.Label
        ScoutImageSizeEditField         matlab.ui.control.NumericEditField
        ScoutImageProjectionsEditFieldLabel  matlab.ui.control.Label
        ScoutImageProjectionsEditField  matlab.ui.control.NumericEditField
        SubjectIdentifierEditFieldLabel  matlab.ui.control.Label
        SubjectIdentifierEditField      matlab.ui.control.EditField
        DesiredImageSizeEditField       matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        DetectDiaphragmMotionandGatePanel  matlab.ui.container.Panel
        x1y1EditFieldLabel              matlab.ui.control.Label
        x1EditField                     matlab.ui.control.NumericEditField
        y1EditField                     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        y2EditField                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        x2y2EditFieldLabel              matlab.ui.control.Label
        x2EditField                     matlab.ui.control.NumericEditField
        DiaphragmSelectionButtonGroup   matlab.ui.container.ButtonGroup
        DifferentialButton              matlab.ui.control.RadioButton
        OtsusMethodButton               matlab.ui.control.RadioButton
        Lamp_3                          matlab.ui.control.Lamp
        GateButton                      matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        CleanGatingBinsPanel            matlab.ui.container.Panel
        RemoveBinsEditField             matlab.ui.control.NumericEditField
        RemoveBinNumberButton           matlab.ui.control.Button
        ReconstructImagesPanel          matlab.ui.container.Panel
        ReconstructAllBinsButton        matlab.ui.control.Button
        CombineandReconButton           matlab.ui.control.Button
        CombineBinsEditField            matlab.ui.control.NumericEditField
        CombineBinsEditField_2          matlab.ui.control.NumericEditField
        CombineBinsEditField_3          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Raw_ready; % Description
        FID;
        Traj;
        ImSize;
        FOV;
        MyPath;
        All_Im; % Description
        Gating;
        Diaphragm_Pos; % Description
        Twix;
    end
    
    methods (Access = private)
        
        function reset_app(app)
            %% Reset Panel 1
            app.FID = [];
            app.Traj = [];
            app.ImSize = [];
            app.FOV = [];
            app.MyPath = 0;
            app.AcquiredImageSizeEditField.Value = 0;
            app.check_raw();
            if app.AcquiredImageSizeEditField.Value == 0 || app.Raw_ready == 0
                app.ReconstructScoutImageButton.Enable = false;
            else
                app.ReconstructScoutImageButton.Enable = true;
            end
            app.Lamp.Color = [1 0 0];
            %% Reset Panel 2
            app.CoilsEditField.Value = 0;
            app.CoilsEditField_2.Value = 0;
            app.SliceEditField.Value = 0;
            app.PercentCompleteGauge.Value = 0;
            app.Lamp_2.Color = [1 0 0];
            if app.SliceEditField.Value == 0 || app.CoilsEditField.Value == 0
                app.SlidingWindowReconButton.Enable = false;
            end
            cla(app.UIAxes);
            %% Reset Panel 3
            app.x1EditField.Value = 0;
            app.y1EditField.Value = 0;
            app.x2EditField.Value = 0;
            app.y2EditField.Value = 0;
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.GateButton.Enable = false;
            app.Lamp_3.Color = [1 0 0];
        end
        
        function check_raw(app)
            fidgood = 0;
            trajgood = 0;
            if ~isempty(app.FID)
                app.FIDsAvailableCheckBox.Value = 1;
                fidgood = 1;
            else
                app.FIDsAvailableCheckBox.Value = 0;
            end
        
            if ~isempty(app.Traj)
                app.TrajectoriesAvailableCheckBox.Value = 1;
                trajgood = 1;
            else
                app.TrajectoriesAvailableCheckBox.Value = 0;
            end
            app.Raw_ready = fidgood*trajgood;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.check_raw();
            app.reset_app();
        end

        % Button pushed function: LoadRadialButton
        function LoadRadialButtonPushed(app, event)
            app.reset_app();
            [app.FID,app.Traj,app.ImSize,app.FOV,app.MyPath,app.Twix] = load_radial_data();
            app.check_raw();
            app.AcquiredImageSizeEditField.Value = app.ImSize;
            app.FieldofViewEditField.Value = app.FOV;
            if app.AcquiredImageSizeEditField.Value == 0 || app.Raw_ready == 0
                app.ReconstructScoutImageButton.Enable = false;
            else
                app.ReconstructScoutImageButton.Enable = true;
            end
        end

        % Button pushed function: ReconstructScoutImageButton
        function ReconstructScoutImageButtonPushed(app, event)
            ONE_select_slice_coils(app.FID,app.Traj,app.AcquiredImageSizeEditField.Value,app.ScoutImageSizeEditField.Value,app.ScoutImageProjectionsEditField.Value);
            app.Lamp.Color = [0 1 0];
        end

        % Value changed function: AcquiredImageSizeEditField
        function AcquiredImageSizeEditFieldValueChanged(app, event)
            app.check_raw();
            if app.AcquiredImageSizeEditField.Value == 0 || app.Raw_ready == 0
                app.ReconstructScoutImageButton.Enable = false;
            else
                app.ReconstructScoutImageButton.Enable = true;
            end
        end

        % Value changed function: ScoutImageProjectionsEditField
        function ScoutImageProjectionsEditFieldValueChanged(app, event)
            app.check_raw();
            if app.AcquiredImageSizeEditField.Value == 0 || app.Raw_ready == 0
                app.ReconstructScoutImageButton.Enable = false;
            else
                app.ReconstructScoutImageButton.Enable = true;
            end
        end

        % Value changed function: ScoutImageSizeEditField
        function ScoutImageSizeEditFieldValueChanged(app, event)
            app.check_raw();
            if app.AcquiredImageSizeEditField.Value == 0 || app.Raw_ready == 0
                app.ReconstructScoutImageButton.Enable = false;
            else
                app.ReconstructScoutImageButton.Enable = true;
            end
        end

        % Value changed function: FieldofViewEditField
        function FieldofViewEditFieldValueChanged(app, event)
            app.check_raw();
            if app.AcquiredImageSizeEditField.Value == 0 || app.Raw_ready == 0
                app.ReconstructScoutImageButton.Enable = false;
            else
                app.ReconstructScoutImageButton.Enable = true;
            end
        end

        % Value changed function: CoilsEditField
        function CoilsEditFieldValueChanged(app, event)
            if app.SliceEditField.Value ~= 0 && app.CoilsEditField.Value ~= 0
                app.SlidingWindowReconButton.Enable = true;
            else
                app.SlidingWindowReconButton.Enable = false;
            end
        end

        % Value changed function: SliceEditField
        function SliceEditFieldValueChanged(app, event)
            if app.SliceEditField.Value ~= 0 && app.CoilsEditField.Value ~= 0
                app.SlidingWindowReconButton.Enable = true;
            else
                app.SlidingWindowReconButton.Enable = false;
            end
        end

        % Value changed function: CoilsEditField_2
        function CoilsEditField_2ValueChanged(app, event)
            if app.SliceEditField.Value ~= 0 && app.CoilsEditField.Value ~= 0
                app.SlidingWindowReconButton.Enable = true;
            else
                app.SlidingWindowReconButton.Enable = false;
            end
        end

        % Value changed function: DimensionSlider
        function DimensionSliderValueChanged(app, event)
            value = app.DimensionSlider.Value;
            app.DimensionSlider.Value = round(value);
        end

        % Button pushed function: SlidingWindowReconButton
        function SlidingWindowReconButtonPushed(app, event)

            if app.CoilsEditField_2.Value == 0
                coils = app.CoilsEditField.Value;
            else
                coils = [app.CoilsEditField.Value app.CoilsEditField_2.Value];
            end
            dim = app.DimensionSlider.Value;
            fid = app.FID(:,:,coils);
            slice = app.SliceEditField.Value;
            if app.AcquiredImageSizeEditField.Value ~= app.ScoutImageSizeEditField.Value
                [myfid,mytraj] = ImTools.change_mat_size(fid,app.Traj,app.ScoutImageSizeEditField.Value,app.AcquiredImageSizeEditField.Value);
            else
                myfid = fid;
                mytraj = app.Traj;
            end
            Small_size = app.ScoutImageSizeEditField.Value;
            Sliding_window_size = app.ScoutImageProjectionsEditField.Value;
            Ind_Ims_Sw = 1:(Sliding_window_size/2):(size(fid,2)-Sliding_window_size);
            Ims = zeros(Small_size,Small_size,length(Ind_Ims_Sw));
            tmpIms = zeros(Small_size,Small_size,Small_size,length(coils));
            nIter = 10;
            counter = 1;
            for i = Ind_Ims_Sw
                disp(['Starting Recon ' num2str(counter) ' of ' num2str(length(Ind_Ims_Sw))]);
                tmpfid = myfid(:,i:(i+Sliding_window_size),:);
                tmptraj = mytraj(:,:,i:(i+Sliding_window_size));
                tmpDCF = Recon.get_DCF(tmptraj,Small_size,nIter);
                for j = 1:length(coils)
                    tmpIms(:,:,:,j) = Recon.mem_eff_recon(Small_size,tmpfid(:,:,j),tmptraj,tmpDCF,j,size(myfid,3),[0 0 0],false);
                end
                if length(coils)>1
                    tmpIm = Tools.soscoilcombine(tmpIms);
                else
                    tmpIm = abs(tmpIms);
                end
                if dim==1
                    Ims(:,:,counter) = squeeze(tmpIm(slice,:,:));
                elseif dim == 2
                    Ims(:,:,counter) = squeeze(tmpIm(:,slice,:));
                elseif dim == 3
                    Ims(:,:,counter) = squeeze(tmpIm(:,:,slice));
                else
                    error('dim needs to be 1,2, or 3');
                end
                app.PercentCompleteGauge.Value = (counter/length(Ind_Ims_Sw))*100;
                drawnow
                disp(['Done with Recon ' num2str(counter) ' of ' num2str(length(Ind_Ims_Sw))]);
                counter = counter + 1;
            end
            app.Lamp_2.Color = [0 1 0];
            app.All_Im = Ims;
            imagesc(app.UIAxes,squeeze(app.All_Im(:,:,1)));
            axis(app.UIAxes,"square");
            axis(app.UIAxes,"off");
            colormap(app.UIAxes,"gray");
        end

        % Value changed function: x1EditField
        function x1EditFieldValueChanged(app, event)
            if app.x1EditField.Value ~=0 && app.x2EditField.Value ~=0 && app.y1EditField.Value ~=0 && app.y2EditField.Value ~=0
                app.GateButton.Enable = true;
                imagesc(app.UIAxes,squeeze(app.All_Im(:,:,1)));
                axis(app.UIAxes,"square");
                axis(app.UIAxes,"off");
                colormap(app.UIAxes,"gray");
                hold(app.UIAxes,"on");
                plot(app.UIAxes,[app.x1EditField.Value app.x2EditField.Value],[app.y1EditField.Value app.y2EditField.Value],'g','LineWidth',2);
                hold(app.UIAxes,"off");
            else
                app.GateButton.Enable = false;
            end
        end

        % Value changed function: y1EditField
        function y1EditFieldValueChanged(app, event)
            if app.x1EditField.Value ~=0 && app.x2EditField.Value ~=0 && app.y1EditField.Value ~=0 && app.y2EditField.Value ~=0
                app.GateButton.Enable = true;
                imagesc(app.UIAxes,squeeze(app.All_Im(:,:,1)));
                axis(app.UIAxes,"square");
                axis(app.UIAxes,"off");
                colormap(app.UIAxes,"gray");
                hold(app.UIAxes,"on");
                plot(app.UIAxes,[app.x1EditField.Value app.x2EditField.Value],[app.y1EditField.Value app.y2EditField.Value],'g','LineWidth',2);
                hold(app.UIAxes,"off");
            else
                app.GateButton.Enable = false;
            end
        end

        % Value changed function: y2EditField
        function y2EditFieldValueChanged(app, event)
            if app.x1EditField.Value ~=0 && app.x2EditField.Value ~=0 && app.y1EditField.Value ~=0 && app.y2EditField.Value ~=0
                app.GateButton.Enable = true;
                hold(app.UIAxes,"on");
                plot(app.UIAxes,[app.x1EditField.Value app.x2EditField.Value],[app.y1EditField.Value app.y2EditField.Value],'g','LineWidth',2);
                hold(app.UIAxes,"off");
            else
                app.GateButton.Enable = false;
            end
        end

        % Value changed function: x2EditField
        function x2EditFieldValueChanged(app, event)
            if app.x1EditField.Value ~=0 && app.x2EditField.Value ~=0 && app.y1EditField.Value ~=0 && app.y2EditField.Value ~=0
                app.GateButton.Enable = true;
                imagesc(app.UIAxes,squeeze(app.All_Im(:,:,1)));
                axis(app.UIAxes,"square");
                axis(app.UIAxes,"off");
                colormap(app.UIAxes,"gray");
                hold(app.UIAxes,"on");
                plot(app.UIAxes,[app.x1EditField.Value app.x2EditField.Value],[app.y1EditField.Value app.y2EditField.Value],'g','LineWidth',2);
                hold(app.UIAxes,"off");
            else
                app.GateButton.Enable = false;
            end
        end

        % Selection changed function: DiaphragmSelectionButtonGroup
        function DiaphragmSelectionButtonGroupSelectionChanged(app, event)
            if app.x1EditField.Value ~=0 && app.x2EditField.Value ~=0 && app.y1EditField.Value ~=0 && app.y2EditField.Value ~=0
                app.GateButton.Enable = true;
                imagesc(app.UIAxes,squeeze(app.All_Im(:,:,1)));
                axis(app.UIAxes,"square");
                axis(app.UIAxes,"off");
                colormap(app.UIAxes,"gray");
                hold(app.UIAxes,"on");
                plot(app.UIAxes,[app.x1EditField.Value app.x2EditField.Value],[app.y1EditField.Value app.y2EditField.Value],'g','LineWidth',2);
                hold(app.UIAxes,"off");
            else
                app.GateButton.Enable = false;
            end
            app.Lamp_3.Color = [1 0 0];
        end

        % Button pushed function: GateButton
        function GateButtonPushed(app, event)
            X = [app.x1EditField.Value app.x2EditField.Value];
            Y = [app.y1EditField.Value app.y2EditField.Value];
            All_Ims = app.All_Im;
            Lines = zeros(max([X(2)-X(1),Y(2)-Y(1)]),size(All_Ims,3));
            dLines = zeros((max([X(2)-X(1),Y(2)-Y(1)]))-1,size(All_Ims,3));
            diaphragm_pos = zeros(1,size(All_Ims,3));
            for i = 1:size(All_Ims,3)
                pts = Lines(:,i);
                if Y(1) == Y(2)
                    Lines(:,i) = All_Ims(Y(1):Y(2),(X(1)+1):X(2),i);
                else
                    Lines(:,i) = All_Ims((Y(1)+1):Y(2),X(1):X(2),i);
                end
                diffline = abs(diff(Lines(:,i)));
                dLines(1:length(diffline),i) = abs(diff(Lines(:,i)));
                [~,diaphragm_pos(i)] = max(dLines(:,i));
            end
            My_thresh = graythresh(Lines);
            d_pos_2 = zeros(1,size(All_Ims,3));
            if Lines(1,1)<Lines(end,1)
                for i = 1:size(All_Ims,3)
                    d_pos_2(i) = find(Lines(:,i)<My_thresh,1,'last');
                end
            else
                for i = 1:size(All_Ims,3)
                    d_pos_2(i) = find(Lines(:,i)>My_thresh,1,'last');
                end
            end
            imagesc(app.UIAxes2,Lines);
            axis(app.UIAxes2,"off");
            colormap(app.UIAxes2,"gray");
            hold(app.UIAxes2,"on");
            if app.DifferentialButton.Value
                app.Diaphragm_Pos = diaphragm_pos;
            else
                app.Diaphragm_Pos = d_pos_2;
            end
            plot(app.UIAxes2,1:size(All_Ims,3),app.Diaphragm_Pos,'g','LineWidth',1);
            
            %% Now, get the actual gating indices
            NPro = size(app.FID,2);
            
            first_pos = min(app.Diaphragm_Pos);
            last_pos = max(app.Diaphragm_Pos);
            
            positions = first_pos:last_pos;
            
            my_index = zeros(length(positions),NPro);
            sliding_window = app.ScoutImageProjectionsEditField.Value;
            
            index_count = 1;
            for i = positions
               locs = find(diaphragm_pos==i);
               for j = 1:length(locs)
                   bfr_locs = (locs(j)-1)*sliding_window/2; 
                   my_index(index_count,(bfr_locs+1):(bfr_locs+sliding_window))=1;
               end
               index_count = index_count+1;
            end
            app.Gating = my_index;
            imagesc(app.UIAxes3,app.Gating);
            app.Lamp_3.Color = [0 1 0];
        end

        % Button pushed function: RemoveBinNumberButton
        function RemoveBinNumberButtonPushed(app, event)
            if app.RemoveBinsEditField.Value > 0 && app.RemoveBinsEditField.Value <= size(app.Gating,1)
                app.Gating(app.RemoveBinsEditField.Value,:) = [];
                imagesc(app.UIAxes3,app.Gating);
            end
        end

        % Button pushed function: ReconstructAllBinsButton
        function ReconstructAllBinsButtonPushed(app, event)
            if app.DesiredImageSizeEditField.Value == 0
                my_imsize = app.AcquiredImageSizeEditField.Value;
            else
                my_imsize = app.DesiredImageSizeEditField.Value;
            end
            FIVE_Reconstruct_Images(app.FID,app.Traj,app.AcquiredImageSizeEditField.Value,app.Gating,app.MyPath,app.FieldofViewEditField.Value,my_imsize);
        end

        % Button pushed function: CombineandReconButton
        function CombineandReconButtonPushed(app, event)
            combinebins = [app.CombineBinsEditField.Value app.CombineBinsEditField_2.Value app.CombineBinsEditField_3.Value];
            combinebins(combinebins == 0) = [];
            Patient_Name = app.SubjectIdentifierEditField.Value;
            FIVEB_Reconstruct_Combined_Image(app.FID,app.Traj,app.AcquiredImageSizeEditField.Value,app.Gating,app.MyPath,app.FieldofViewEditField.Value,combinebins,app.AcquiredImageSizeEditField.Value,app.Twix,Patient_Name)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExamineDiaphragmandCoilsPanel = uipanel(app.UIFigure);
            app.ExamineDiaphragmandCoilsPanel.Title = '1. Examine Diaphragm and Coils';
            app.ReconstructScoutImageButton = uibutton(app.ExamineDiaphragmandCoilsPanel, 'push');
            app.ReconstructScoutImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructScoutImageButtonPushed, true);
            app.ReconstructScoutImageButton.Text = 'Reconstruct Scout Image';
            app.Lamp = uilamp(app.ExamineDiaphragmandCoilsPanel);
            app.SlidingWindowReconPanel = uipanel(app.UIFigure);
            app.SlidingWindowReconPanel.Title = '2. Sliding Window Recon';
            app.DimensionSliderLabel = uilabel(app.SlidingWindowReconPanel);
            app.DimensionSliderLabel.Text = 'Dimension';
            app.DimensionSlider = uislider(app.SlidingWindowReconPanel);
            app.DimensionSlider.Limits = [1 3];
            app.DimensionSlider.MajorTicks = [1 2 3];
            app.DimensionSlider.ValueChangedFcn = createCallbackFcn(app, @DimensionSliderValueChanged, true);
            app.DimensionSlider.MinorTicks = [1 2 3];
            app.DimensionSlider.Value = 2;
            app.SlidingWindowReconButton = uibutton(app.SlidingWindowReconPanel, 'push');
            app.SlidingWindowReconButton.ButtonPushedFcn = createCallbackFcn(app, @SlidingWindowReconButtonPushed, true);
            app.SlidingWindowReconButton.Text = 'Sliding Window Recon';
            app.PercentCompleteGaugeLabel = uilabel(app.SlidingWindowReconPanel);
            app.PercentCompleteGaugeLabel.Text = 'Percent Complete';
            app.PercentCompleteGauge = uigauge(app.SlidingWindowReconPanel, 'semicircular');
            app.Lamp_2 = uilamp(app.SlidingWindowReconPanel);
            app.SliceEditField_2Label = uilabel(app.SlidingWindowReconPanel);
            app.SliceEditField_2Label.Text = 'Slice';
            app.SliceEditField = uieditfield(app.SlidingWindowReconPanel, 'numeric');
            app.SliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceEditFieldValueChanged, true);
            app.CoilsEditFieldLabel = uilabel(app.SlidingWindowReconPanel);
            app.CoilsEditFieldLabel.Text = 'Coils';
            app.CoilsEditField = uieditfield(app.SlidingWindowReconPanel, 'numeric');
            app.CoilsEditField.ValueChangedFcn = createCallbackFcn(app, @CoilsEditFieldValueChanged, true);
            app.CoilsEditField_2 = uieditfield(app.SlidingWindowReconPanel, 'numeric');
            app.CoilsEditField_2.ValueChangedFcn = createCallbackFcn(app, @CoilsEditField_2ValueChanged, true);
            app.DataAvailablePanel = uipanel(app.UIFigure);
            app.DataAvailablePanel.Title = '0. Data Available';
            app.FIDsAvailableCheckBox = uicheckbox(app.DataAvailablePanel);
            app.FIDsAvailableCheckBox.Text = 'FIDs Available';
            app.TrajectoriesAvailableCheckBox = uicheckbox(app.DataAvailablePanel);
            app.TrajectoriesAvailableCheckBox.Text = 'Trajectories Available';
            app.LoadRadialButton = uibutton(app.DataAvailablePanel, 'push');
            app.LoadRadialButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRadialButtonPushed, true);
            app.LoadRadialButton.Text = 'Load Radial';
            app.LoadSpiralButton = uibutton(app.DataAvailablePanel, 'push');
            app.LoadSpiralButton.Text = 'Load Spiral';
            app.ConsistentValuesPanel = uipanel(app.UIFigure);
            app.ConsistentValuesPanel.Title = 'Consistent Values';
            app.AcquiredDesiredImageSizeEditFieldLabel = uilabel(app.ConsistentValuesPanel);
            app.AcquiredDesiredImageSizeEditFieldLabel.Text = 'Acquired/Desired Image Size';
            app.AcquiredImageSizeEditField = uieditfield(app.ConsistentValuesPanel, 'numeric');
            app.AcquiredImageSizeEditField.ValueChangedFcn = createCallbackFcn(app, @AcquiredImageSizeEditFieldValueChanged, true);
            app.FieldofViewEditFieldLabel = uilabel(app.ConsistentValuesPanel);
            app.FieldofViewEditFieldLabel.Text = 'Field of View';
            app.FieldofViewEditField = uieditfield(app.ConsistentValuesPanel, 'numeric');
            app.FieldofViewEditField.ValueChangedFcn = createCallbackFcn(app, @FieldofViewEditFieldValueChanged, true);
            app.ScoutImageSizeEditFieldLabel = uilabel(app.ConsistentValuesPanel);
            app.ScoutImageSizeEditFieldLabel.Text = 'Scout Image Size';
            app.ScoutImageSizeEditField = uieditfield(app.ConsistentValuesPanel, 'numeric');
            app.ScoutImageSizeEditField.ValueChangedFcn = createCallbackFcn(app, @ScoutImageSizeEditFieldValueChanged, true);
            app.ScoutImageSizeEditField.Value = 96;
            app.ScoutImageProjectionsEditFieldLabel = uilabel(app.ConsistentValuesPanel);
            app.ScoutImageProjectionsEditFieldLabel.Text = 'Scout Image Projections';
            app.ScoutImageProjectionsEditField = uieditfield(app.ConsistentValuesPanel, 'numeric');
            app.ScoutImageProjectionsEditField.ValueChangedFcn = createCallbackFcn(app, @ScoutImageProjectionsEditFieldValueChanged, true);
            app.ScoutImageProjectionsEditField.Value = 200;
            app.SubjectIdentifierEditFieldLabel = uilabel(app.ConsistentValuesPanel);
            app.SubjectIdentifierEditFieldLabel.Text = 'Subject Identifier';
            app.SubjectIdentifierEditField = uieditfield(app.ConsistentValuesPanel, 'text');
            app.SubjectIdentifierEditField.Value = 'Test_Subject';
            app.DesiredImageSizeEditField = uieditfield(app.ConsistentValuesPanel, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Diaphragm Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DetectDiaphragmMotionandGatePanel = uipanel(app.UIFigure);
            app.DetectDiaphragmMotionandGatePanel.Title = '3. Detect Diaphragm Motion and Gate';
            app.x1y1EditFieldLabel = uilabel(app.DetectDiaphragmMotionandGatePanel);
            app.x1y1EditFieldLabel.Text = '(x1,y1)';
            app.x1EditField = uieditfield(app.DetectDiaphragmMotionandGatePanel, 'numeric');
            app.x1EditField.ValueChangedFcn = createCallbackFcn(app, @x1EditFieldValueChanged, true);
            app.y1EditField = uieditfield(app.DetectDiaphragmMotionandGatePanel, 'numeric');
            app.y1EditField.ValueChangedFcn = createCallbackFcn(app, @y1EditFieldValueChanged, true);
            app.Label = uilabel(app.DetectDiaphragmMotionandGatePanel);
            app.Label.Text = '(';
            app.Label_2 = uilabel(app.DetectDiaphragmMotionandGatePanel);
            app.Label_2.Text = ')';
            app.Label_3 = uilabel(app.DetectDiaphragmMotionandGatePanel);
            app.Label_3.Text = ',';
            app.y2EditField = uieditfield(app.DetectDiaphragmMotionandGatePanel, 'numeric');
            app.y2EditField.ValueChangedFcn = createCallbackFcn(app, @y2EditFieldValueChanged, true);
            app.Label_4 = uilabel(app.DetectDiaphragmMotionandGatePanel);
            app.Label_4.Text = '(';
            app.Label_5 = uilabel(app.DetectDiaphragmMotionandGatePanel);
            app.Label_5.Text = ')';
            app.Label_6 = uilabel(app.DetectDiaphragmMotionandGatePanel);
            app.Label_6.Text = ',';
            app.x2y2EditFieldLabel = uilabel(app.DetectDiaphragmMotionandGatePanel);
            app.x2y2EditFieldLabel.Text = '(x2,y2)';
            app.x2EditField = uieditfield(app.DetectDiaphragmMotionandGatePanel, 'numeric');
            app.x2EditField.ValueChangedFcn = createCallbackFcn(app, @x2EditFieldValueChanged, true);
            app.DiaphragmSelectionButtonGroup = uibuttongroup(app.DetectDiaphragmMotionandGatePanel);
            app.DiaphragmSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DiaphragmSelectionButtonGroupSelectionChanged, true);
            app.DiaphragmSelectionButtonGroup.Title = 'Diaphragm Selection';
            app.DifferentialButton = uiradiobutton(app.DiaphragmSelectionButtonGroup);
            app.DifferentialButton.Text = 'Differential';
            app.DifferentialButton.Value = true;
            app.OtsusMethodButton = uiradiobutton(app.DiaphragmSelectionButtonGroup);
            app.OtsusMethodButton.Text = 'Otsu''s Method';
            app.Lamp_3 = uilamp(app.DetectDiaphragmMotionandGatePanel);
            app.GateButton = uibutton(app.DetectDiaphragmMotionandGatePanel, 'push');
            app.GateButton.ButtonPushedFcn = createCallbackFcn(app, @GateButtonPushed, true);
            app.GateButton.Text = 'Gate';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Diaphragm Motion')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Gating Bins')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.CleanGatingBinsPanel = uipanel(app.UIFigure);
            app.CleanGatingBinsPanel.Title = '4. Clean Gating Bins';
            app.RemoveBinsEditField = uieditfield(app.CleanGatingBinsPanel, 'numeric');
            app.RemoveBinNumberButton = uibutton(app.CleanGatingBinsPanel, 'push');
            app.RemoveBinNumberButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBinNumberButtonPushed, true);
            app.RemoveBinNumberButton.Text = 'Remove Bin Number';
            app.ReconstructImagesPanel = uipanel(app.UIFigure);
            app.ReconstructImagesPanel.Title = '5. Reconstruct Images';
            app.ReconstructAllBinsButton = uibutton(app.ReconstructImagesPanel, 'push');
            app.ReconstructAllBinsButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructAllBinsButtonPushed, true);
            app.ReconstructAllBinsButton.Text = 'Reconstruct All Bins';
            app.CombineandReconButton = uibutton(app.ReconstructImagesPanel, 'push');
            app.CombineandReconButton.ButtonPushedFcn = createCallbackFcn(app, @CombineandReconButtonPushed, true);
            app.CombineandReconButton.Text = 'Combine and Recon';
            app.CombineBinsEditField = uieditfield(app.ReconstructImagesPanel, 'numeric');
            app.CombineBinsEditField_2 = uieditfield(app.ReconstructImagesPanel, 'numeric');
            app.CombineBinsEditField_3 = uieditfield(app.ReconstructImagesPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Based_Retrospective_Gating

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end